classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ProgramStatesLabel         matlab.ui.control.Label
        RunningLamp                matlab.ui.control.Lamp
        RunningLampLabel           matlab.ui.control.Label
        UpdatePortsButton          matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        BaudRateDropDown           matlab.ui.control.DropDown
        BaudRateDropDownLabel      matlab.ui.control.Label
        SerialPortDropDown         matlab.ui.control.DropDown
        SerialPortDropDownLabel    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        SensorTab                  matlab.ui.container.Tab
        Encoder3Gauge              matlab.ui.control.Gauge
        Encoder3GaugeLabel         matlab.ui.control.Label
        Encoder2Gauge              matlab.ui.control.Gauge
        Encoder2GaugeLabel         matlab.ui.control.Label
        Encoder1Gauge              matlab.ui.control.Gauge
        Encoder1GaugeLabel         matlab.ui.control.Label
        LocationTab                matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        SerialStreamTextArea       matlab.ui.control.TextArea
        SerialStreamTextAreaLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        availablePorts % The currently available COM ports
        port % Description
        running = false % Description
    end
    
    methods (Access = private)
        
        
    end
    
    methods (Access = public)
        
        
        function results = MainLoop(app)
            app.SerialStreamTextArea.Value = "Running!"
            app.RunningLamp.Color = "Green"
            running = true
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.MainLoop();
        end

        % Button pushed function: UpdatePortsButton
        function UpdatePortsButtonPushed(app, event)
            app.availablePorts = serialportlist
            app.SerialPortDropDown.Items = app.availablePorts
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {533, 533};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SerialPortDropDownLabel = uilabel(app.LeftPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.LeftPanel);
            app.SerialPortDropDown.Items = {'None'};
            app.SerialPortDropDown.Placeholder = 'None';
            app.SerialPortDropDown.Value = 'None';
            app.BaudRateDropDownLabel = uilabel(app.LeftPanel);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.LeftPanel);
            app.BaudRateDropDown.Items = {'9600'};
            app.BaudRateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.UpdatePortsButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePortsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePortsButtonPushed, true);
            app.UpdatePortsButton.Text = 'Update Ports';
            app.RunningLampLabel = uilabel(app.LeftPanel);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.LeftPanel);
            app.ProgramStatesLabel = uilabel(app.LeftPanel);
            app.ProgramStatesLabel.Text = 'Program States';
            app.RightPanel = uipanel(app.GridLayout);
            app.SerialStreamTextAreaLabel = uilabel(app.RightPanel);
            app.SerialStreamTextAreaLabel.Text = 'Serial Stream';
            app.SerialStreamTextArea = uitextarea(app.RightPanel);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SensorTab = uitab(app.TabGroup);
            app.SensorTab.Title = 'Sensor';
            app.Encoder1GaugeLabel = uilabel(app.SensorTab);
            app.Encoder1GaugeLabel.Text = 'Encoder 1';
            app.Encoder1Gauge = uigauge(app.SensorTab, 'circular');
            app.Encoder1Gauge.Limits = [-180 180];
            app.Encoder1Gauge.Tag = 'e1';
            app.Encoder2GaugeLabel = uilabel(app.SensorTab);
            app.Encoder2GaugeLabel.Text = 'Encoder 2';
            app.Encoder2Gauge = uigauge(app.SensorTab, 'circular');
            app.Encoder2Gauge.Limits = [-180 180];
            app.Encoder2Gauge.Tag = 'e2';
            app.Encoder3GaugeLabel = uilabel(app.SensorTab);
            app.Encoder3GaugeLabel.Text = 'Encoder 3';
            app.Encoder3Gauge = uigauge(app.SensorTab, 'circular');
            app.Encoder3Gauge.Limits = [-180 180];
            app.Encoder3Gauge.Tag = 'e3';
            app.LocationTab = uitab(app.TabGroup);
            app.LocationTab.Title = 'Location';
            app.UIAxes = uiaxes(app.LocationTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end