classdef Main_3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        ClearSerialButton          matlab.ui.control.Button
        DisconnectButton           matlab.ui.control.Button
        ProgramStatesLabel         matlab.ui.control.Label
        COMConnectedLamp           matlab.ui.control.Lamp
        COMConnectedLampLabel      matlab.ui.control.Label
        UpdatePortsButton          matlab.ui.control.Button
        ConnectButton              matlab.ui.control.Button
        BaudRateDropDown           matlab.ui.control.DropDown
        BaudRateDropDownLabel      matlab.ui.control.Label
        SerialPortDropDown         matlab.ui.control.DropDown
        SerialPortDropDownLabel    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        TabGroup                   matlab.ui.container.TabGroup
        SensorTab                  matlab.ui.container.Tab
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        AltitudeLabel              matlab.ui.control.Label
        LatitudeLabel              matlab.ui.control.Label
        LongitudeLabel             matlab.ui.control.Label
        GPSLabel                   matlab.ui.control.Label
        Encoder3Gauge              matlab.ui.control.Gauge
        Encoder3GaugeLabel         matlab.ui.control.Label
        Encoder2Gauge              matlab.ui.control.Gauge
        Encoder2GaugeLabel         matlab.ui.control.Label
        Encoder1Gauge              matlab.ui.control.Gauge
        Encoder1GaugeLabel         matlab.ui.control.Label
        Tab                        matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        SerialStreamTextArea       matlab.ui.control.TextArea
        SerialStreamTextAreaLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        refreshTimer % Timer object
        connected  % COM Port Data Status
        availablePorts % The currently available COM ports
        running % Description
        s  % Serial Object
        count
        port
        data = "";
        incomingData
        recentData
        encoderVal
    end
    
    properties (Access = public)

    end
    
    methods (Access = private)
        
        function refreshTimerFcn(app, ~, event)
            % Get Current Data
            tnow = datetime('now', 'TimeZone', 'America/New_York');
            if app.connected
                try
                    app.recentData = fscanf(app.s);
                    app.data = app.recentData + app.data;
                    app.SerialStreamTextArea.Value = app.data;
                catch ME
                    uialert(app.UIFigure,'COM error related to reading COM port.', 'COM Issue!');
                end
                app.incomingData = split(app.recentData,',');
                app.updateLabels;
            end
        end
        
        
        function updateComPorts(app)
            app.availablePorts = serialportlist
            app.SerialPortDropDown.Items = app.availablePorts
        end

        function updateLabels(app)
            app.Encoder1Gauge.Value = str2double(app.incomingData{2});
            app.Encoder2Gauge.Value = str2double(app.incomingData{3});
            app.Encoder3Gauge.Value = str2double(app.incomingData{4});
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Find COM Ports
            app.updateComPorts;

            % Create timer object
            app.refreshTimer = timer(...
            'ExecutionMode', 'fixedRate', ...      % Run timer repeatedly
            'Period', .1, ...                       % Period is .1 seconds
            'BusyMode', 'queue',...                % Queue timer callbacks when busy
            'TimerFcn', @app.refreshTimerFcn);   % Callback that runs every period
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.refreshTimer.Running, 'off')
               % Start timer
               start(app.refreshTimer);
            end
            try
                app.s = serialport(app.SerialPortDropDown.Value,str2double(app.BaudRateDropDown.Value));
                fopen(app.s);
                app.COMConnectedLamp.Color = "Green";
                app.connected = true;
            catch ME
                uialert(app.UIFigure,'COM error related to starting COM port.', 'COM Issue!');
            end
        end

        % Button pushed function: UpdatePortsButton
        function UpdatePortsButtonPushed(app, event)
            app.updateComPorts;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and figure
            if app.connected
                delete(app.s);
            end
            stop(app.refreshTimer);
            delete(app.refreshTimer);
            delete(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            delete(app.s);
            app.COMConnectedLamp.Color = "red";
            app.connected = false;
            stop(app.refreshTimer);
        end

        % Button pushed function: ClearSerialButton
        function ClearSerialButtonPushed(app, event)
            app.data = "";
            app.SerialStreamTextArea.Value = app.data;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            patch(app.UIAxes,[1,3,3,1],[0,0,2,2],[-1,-1,-1,-1],'blue'); %top
            patch(app.UIAxes,[1,3,3,1],[0,0,2,2],[-2,-2,-2,-2],'blue'); %bottom
            patch(app.UIAxes,[1,1,1,1],[0,0,2,2],[-2,-1,-1,-2],'r'); %left
            patch(app.UIAxes,[3,3,3,3],[0,0,2,2],[-2,-1,-1,-2],'r'); %right
            patch(app.UIAxes,[1,3,3,1],[0,0,0,0],[-2,-2,-1,-1],'g'); %front 
            patch(app.UIAxes,[1,3,3,1],[2,2,2,2],[-2,-2,-1,-1],'g'); %back
            plot3(app.UIAxes,[-5,5],[0,0],[0,0],'-r');
            axis equal
            hold(app.UIAxes, "on");
            plot3(app.UIAxes,[0,0],[-5,5],[0,0],'-b');
            plot3(app.UIAxes,[0,0],[0,0],[-5,5],'-g');
            % red-x blue-y green z
            %add range for xyz
            xlabel(app.UIAxes,'x')
            xlim(app.UIAxes,[-2,4])
            ylabel(app.UIAxes,'y')
            ylim(app.UIAxes,[-2,4])
            zlabel(app.UIAxes,'z')
            zlim(app.UIAxes,[-4,2])

 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {533, 533};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {235, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SerialPortDropDownLabel = uilabel(app.LeftPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.LeftPanel);
            app.SerialPortDropDown.Items = {'None'};
            app.SerialPortDropDown.Placeholder = 'None';
            app.SerialPortDropDown.Value = 'None';
            app.BaudRateDropDownLabel = uilabel(app.LeftPanel);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.LeftPanel);
            app.BaudRateDropDown.Items = {'9600'};
            app.BaudRateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.LeftPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.UpdatePortsButton = uibutton(app.LeftPanel, 'push');
            app.UpdatePortsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePortsButtonPushed, true);
            app.UpdatePortsButton.Text = 'Update Ports';
            app.COMConnectedLampLabel = uilabel(app.LeftPanel);
            app.COMConnectedLampLabel.Text = 'COM Connected';
            app.COMConnectedLamp = uilamp(app.LeftPanel);
            app.ProgramStatesLabel = uilabel(app.LeftPanel);
            app.ProgramStatesLabel.Text = 'Program States';
            app.DisconnectButton = uibutton(app.LeftPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ClearSerialButton = uibutton(app.LeftPanel, 'push');
            app.ClearSerialButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSerialButtonPushed, true);
            app.ClearSerialButton.Text = 'Clear Serial';
            app.RightPanel = uipanel(app.GridLayout);
            app.SerialStreamTextAreaLabel = uilabel(app.RightPanel);
            app.SerialStreamTextAreaLabel.Text = 'Serial Stream';
            app.SerialStreamTextArea = uitextarea(app.RightPanel);
            app.SerialStreamTextArea.Editable = 'off';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SensorTab = uitab(app.TabGroup);
            app.SensorTab.Title = 'Sensor';
            app.Encoder1GaugeLabel = uilabel(app.SensorTab);
            app.Encoder1GaugeLabel.Text = 'Encoder 1';
            app.Encoder1Gauge = uigauge(app.SensorTab, 'circular');
            app.Encoder1Gauge.Limits = [-180 180];
            app.Encoder1Gauge.Tag = 'e1';
            app.Encoder2GaugeLabel = uilabel(app.SensorTab);
            app.Encoder2GaugeLabel.Text = 'Encoder 2';
            app.Encoder2Gauge = uigauge(app.SensorTab, 'circular');
            app.Encoder2Gauge.Limits = [-180 180];
            app.Encoder2Gauge.Tag = 'e2';
            app.Encoder3GaugeLabel = uilabel(app.SensorTab);
            app.Encoder3GaugeLabel.Text = 'Encoder 3';
            app.Encoder3Gauge = uigauge(app.SensorTab, 'circular');
            app.Encoder3Gauge.Limits = [-180 180];
            app.Encoder3Gauge.Tag = 'e3';
            app.GPSLabel = uilabel(app.SensorTab);
            app.GPSLabel.Text = 'GPS';
            app.LongitudeLabel = uilabel(app.SensorTab);
            app.LongitudeLabel.Text = 'Longitude';
            app.LatitudeLabel = uilabel(app.SensorTab);
            app.LatitudeLabel.Text = 'Latitude';
            app.AltitudeLabel = uilabel(app.SensorTab);
            app.AltitudeLabel.Text = 'Altitude';
            app.Label = uilabel(app.SensorTab);
            app.Label.Tag = 'latitude';
            app.Label.Text = '0.0';
            app.Label_2 = uilabel(app.SensorTab);
            app.Label_2.Tag = 'longitude';
            app.Label_2.Text = '0.0';
            app.Label_3 = uilabel(app.SensorTab);
            app.Label_3.Tag = 'altitude';
            app.Label_3.Text = '0.0';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '3D space')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Main_3D

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end